classdef RadarTestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        StartButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        TrackingLampLabel          matlab.ui.control.Label
        TrackingLamp               matlab.ui.control.Lamp
        AquistionLampLabel         matlab.ui.control.Label
        AquistionLamp              matlab.ui.control.Lamp
        TargetAngleEditFieldLabel  matlab.ui.control.Label
        TargetAngleEditField       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        commandStr = 'python start.py';
        system(commandStr);
        S=1;
        if(S==1)
            app.AquistionLamp.Color = [0 1 0];
        end
%         while(S==1)
%             state='pull down state function';
%               if (state=='start')
                    %DO NOTHING
%               end
%               if (state=='aquisition')
                    %app.AquistionLamp.Color = [0 1 0];
%               end
%               if (state=='tracking')
                    %Pull from firebase 2 csv files
                    %Do sum and difference to both arrays
                    %Feed arrays into phdiffmeasure
                    %Post return value to firebass as phase angle
                    %Do we need 2 Tracking status???
                    %Some point update GUI to display location
%               end
%               if (state=='stop')
%                 S=0;
%               end
%             
%         end
        
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
        commandStr = 'python stop.py';
        system(commandStr);
        S=0;
        if(S==0)
            app.AquistionLamp.Color = [0.902 0.902 0.902]
            app.TrackingLamp.Color = [0.902 0.902 0.902];
        end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TrackingLampLabel = uilabel(app.UIFigure);
            app.TrackingLampLabel.Text = 'Tracking';
            app.TrackingLamp = uilamp(app.UIFigure);
            app.AquistionLampLabel = uilabel(app.UIFigure);
            app.AquistionLampLabel.Text = 'Aquistion';
            app.AquistionLamp = uilamp(app.UIFigure);
            app.TargetAngleEditFieldLabel = uilabel(app.UIFigure);
            app.TargetAngleEditFieldLabel.Text = 'Target Angle';
            app.TargetAngleEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = RadarTestGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end